classdef PITCinteractive2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        DoneButton   matlab.ui.control.Button
        Slider       matlab.ui.control.Slider
        SliderLabel  matlab.ui.control.Label
        UIAxes       matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        data_struct % Description
        BranchList
        Quality
        MaxVel
        VoxDims
        PI
        PIvel_val
        Exclude
        startroots
        SearchDist
        LR
        ds
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, data_struct, startroots, LR, Exclude, ds)
            addpath('C:\Users\sdem348\Documents\MATLAB\CHM\QVTplus\functions\postprocessing')
            SearchDist=8;
            app.Slider.Value=8;
            app.ds=ds;
            app.data_struct=data_struct;
            app.BranchList = data_struct.branchList;
            app.Quality    = data_struct.StdvFromMean;
            app.MaxVel     = data_struct.PIvel_val;
            app.VoxDims    = data_struct.VoxDims;
            app.PI         = data_struct.PI_val;
            app.startroots =startroots;
            app.SearchDist =SearchDist;
            app.LR =LR;

            MaxVel     = data_struct.PIvel_val;
	        PIvel_val=zeros([length(MaxVel(:,1)) 1]);
            app.Exclude=Exclude;
            for i=1:length(MaxVel(:,1))
                PIvel_val(i)=(max(MaxVel(i,:))-min(MaxVel(i,:)))./(mean(MaxVel(i,:)));
            end
            app.PIvel_val=PIvel_val
            BranchList=app.BranchList;
            BranchList = [BranchList [1:length(BranchList)]'];
            for i=1:length(Exclude)
                [idx,~]=find(BranchList(:,4)==Exclude(i));
                BranchList(idx,:)=[];
            end
            % Compute Connectivity below
            Mats=compute_conn(startroots,BranchList,SearchDist,LR);

            BranchList=data_struct.branchList;
            BranchList=[BranchList [1:length(BranchList)]'];
            % Apply the voxel dimensions to the matrix location of the branch lists
            BranchList(:,1)=app.data_struct.VoxDims(1).*BranchList(:,1);
            BranchList(:,2)=app.data_struct.VoxDims(2).*BranchList(:,2);
            BranchList(:,3)=app.data_struct.VoxDims(3).*BranchList(:,3);
            % Process Velocity Based Pulsatility (default output is flow based)
            % Compute Lengths Below
            [~,~,~,XYZ] = compute_length(Mats,BranchList,app.PI,app.PIvel_val,app.Quality);
            XYZ(:,3,:)=abs(XYZ(:,3,:)-75);
            Colours=[1 0 0; 0 1 0; 0 0 1];%{'red','green','blue'};
            for i=1:3
                scatter3(app.UIAxes,XYZ(:,1,i),XYZ(:,2,i),XYZ(:,3,i),'MarkerFaceColor',Colours(i,:),'MarkerFaceAlpha',0.2,'MarkerEdgeAlpha',0.0)
                hold(app.UIAxes,'on')
            end
            xlim(app.UIAxes,[0 250])
            ylim(app.UIAxes,[0 250])
            zlim(app.UIAxes,[0 75])
            xticks(app.UIAxes,[])
            xlabel(app.UIAxes,'')
            yticks(app.UIAxes,[])
            ylabel(app.UIAxes,'')
            zticks(app.UIAxes,[])
            zlabel(app.UIAxes,'')
            % colorbar(app.UIAxes)
            % colormap(app.UIAxes,'jet');
            % clim(app.UIAxes,[0 200])
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            app.SearchDist = app.Slider.Value;
            [Az]=get(app.UIAxes, 'View');
            BList = [app.BranchList [1:length(app.BranchList)]'];
            for i=1:length(app.Exclude)
                [idx,~]=find(BList(:,4)==app.Exclude(i));
                BList(idx,:)=[];
            end
            % Compute Connectivity below
            Mats=compute_conn(app.startroots,BList,app.SearchDist,app.LR);
            app.ds.dataArea.Mats=Mats;
            BList=app.BranchList;

            BList=[BList [1:length(BList)]'];
            % Apply the voxel dimensions to the matrix location of the branch lists
            BList(:,1)=app.data_struct.VoxDims(1).*BList(:,1);
            BList(:,2)=app.data_struct.VoxDims(2).*BList(:,2);
            BList(:,3)=app.data_struct.VoxDims(3).*BList(:,3);
            % Process Velocity Based Pulsatility (default output is flow based)
            % Compute Lengths Below
            [PI_scat,~,G,XYZ] = compute_length(Mats,BList,app.PI,app.PIvel_val,app.Quality);
            app.ds.dataArea.PI_scat=PI_scat;
            app.ds.dataArea.G=G;
            XYZ(:,3,:)=abs(XYZ(:,3,:)-75);
            Colours=[1 0 0; 0 1 0; 0 0 1];%{'red','green','blue'};
            hold(app.UIAxes,'off')
            view(app.UIAxes,Az)
            for i=1:3
                scatter3(app.UIAxes,XYZ(:,1,i),XYZ(:,2,i),XYZ(:,3,i),'MarkerFaceColor',Colours(i,:),'MarkerFaceAlpha',0.2,'MarkerEdgeAlpha',0.0)
                hold(app.UIAxes,'on')
            end
            view(app.UIAxes,Az)
            %% UPDATE
            %WHEN THE SLIDER CHANGES DO WORK HERE
        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            value = app.Slider.Value;
            app.ds.dataArea.SearchDist=value;
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.SliderLabel = uilabel(app.UIFigure);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.UIFigure);
            app.Slider.Limits = [0 16];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.Value = 8;
            app.DoneButton = uibutton(app.UIFigure, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PITCinteractive2(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end